classdef URTrayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EditField              matlab.ui.control.NumericEditField
        q7Slider               matlab.ui.control.Slider
        q7SliderLabel          matlab.ui.control.Label
        q6Slider_2             matlab.ui.control.Slider
        q6Slider_2Label        matlab.ui.control.Label
        q5Slider_2             matlab.ui.control.Slider
        q5Slider_2Label        matlab.ui.control.Label
        q1Slider_2             matlab.ui.control.Slider
        q1Slider_2Label        matlab.ui.control.Label
        q4Slider_2             matlab.ui.control.Slider
        q3Slider_2             matlab.ui.control.Slider
        q2Slider_2             matlab.ui.control.Slider
        q6Slider               matlab.ui.control.Slider
        q6SliderLabel          matlab.ui.control.Label
        q5Slider               matlab.ui.control.Slider
        q5SliderLabel          matlab.ui.control.Label
        q4Slider               matlab.ui.control.Slider
        q4SliderLabel          matlab.ui.control.Label
        q3Slider               matlab.ui.control.Slider
        q3SliderLabel          matlab.ui.control.Label
        q2Slider               matlab.ui.control.Slider
        q2SliderLabel          matlab.ui.control.Label
        q1Slider               matlab.ui.control.Slider
        q1SliderLabel          matlab.ui.control.Label
        Switch                 matlab.ui.control.Switch
        ToggleRobotToJogLabel  matlab.ui.control.Label
        ESTOPButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot % Description
    end
    
    methods (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, robotModel)
            app.robot = robotModel;
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            systemRunning = 0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if strcmp(value, 'UR3e')

                 app.q1Slider.Visible = 'off';
                 app.q2Slider.Visible = 'off';
                 app.q3Slider.Visible = 'off';
                 app.q4Slider.Visible = 'off';
                 app.q5Slider.Visible = 'off';
                 app.q6Slider.Visible = 'off';
                 app.q7Slider.Visible = 'off';
                 app.q7SliderLabel.Visible = 'off';

                 app.q1Slider_2.Visible = 'on';
                 app.q2Slider_2.Visible = 'on';
                 app.q3Slider_2.Visible = 'on';
                 app.q4Slider_2.Visible = 'on';
                 app.q5Slider_2.Visible = 'on';
                 app.q6Slider_2.Visible = 'on';


                 app.q1Slider_2.Limits = [app.robot.qlim(1,1), app.robot.qlim(1,2)];


            else


                 app.q1Slider.Visible = 'on';
                 app.q2Slider.Visible = 'on';
                 app.q3Slider.Visible = 'on';
                 app.q4Slider.Visible = 'on';
                 app.q5Slider.Visible = 'on';
                 app.q6Slider.Visible = 'on';
                 app.q7Slider.Visible = 'on';
                 app.q7SliderLabel.Visible = 'on';

                 app.q1Slider_2.Visible = 'off';   
                 app.q2Slider_2.Visible = 'off';
                 app.q3Slider_2.Visible = 'off';
                 app.q4Slider_2.Visible = 'off';
                 app.q5Slider_2.Visible = 'off';
                 app.q6Slider_2.Visible = 'off';

            end

            
        end

        % Value changing function: q1Slider_2
        function q1Slider_2ValueChanging(app, event)
            app.EditField.Value = event.Value;


             h = findobj('Tag', app.robot.name);
            
            
            % find the graphical element of this name
            if isempty(h)
                error('RTB:teach:badarg', 'No graphical robot of this name found');
            end
            % get the info from its Userdata
            info = get(h(1), 'UserData');
            
            if ~isempty(1)
                % update the stored joint coordinates
                info.q(1) = event.Value;
                % and save it back to the graphical object
                set(h(1), 'UserData', info);
            end
            
            % update all robots of this name
            animate(app.robot, info.q);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.ToggleRobotToJogLabel = uilabel(app.UIFigure);
            app.ToggleRobotToJogLabel.Text = 'Toggle Robot To Jog';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'URTray', 'UR3e'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'URTray';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.MajorTicks = [];
            app.q1Slider.MinorTicks = [];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.MajorTicks = [];
            app.q2Slider.MinorTicks = [];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.MajorTicks = [];
            app.q3Slider.MinorTicks = [];
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.MajorTicks = [];
            app.q4Slider.MinorTicks = [];
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.MajorTicks = [];
            app.q5Slider.MinorTicks = [];
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.MajorTicks = [];
            app.q6Slider.MinorTicks = [];
            app.q2Slider_2 = uislider(app.UIFigure);
            app.q2Slider_2.MajorTicks = [];
            app.q2Slider_2.MinorTicks = [];
            app.q3Slider_2 = uislider(app.UIFigure);
            app.q3Slider_2.MajorTicks = [];
            app.q3Slider_2.MinorTicks = [];
            app.q4Slider_2 = uislider(app.UIFigure);
            app.q4Slider_2.MajorTicks = [];
            app.q4Slider_2.MinorTicks = [];
            app.q1Slider_2Label = uilabel(app.UIFigure);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UIFigure);
            app.q1Slider_2.MajorTicks = [];
            app.q1Slider_2.ValueChangingFcn = createCallbackFcn(app, @q1Slider_2ValueChanging, true);
            app.q1Slider_2.MinorTicks = [];
            app.q5Slider_2Label = uilabel(app.UIFigure);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UIFigure);
            app.q5Slider_2.MajorTicks = [];
            app.q5Slider_2.MinorTicks = [];
            app.q6Slider_2Label = uilabel(app.UIFigure);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UIFigure);
            app.q6Slider_2.MajorTicks = [];
            app.q6Slider_2.MinorTicks = [];
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.MajorTicks = [];
            app.q7Slider.MinorTicks = [];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = URTrayGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end